classdef va2nafx_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        va2nafxUIFigure          matlab.ui.Figure
        SubjectAgeDropDownLabel  matlab.ui.control.Label
        SubjectAge               matlab.ui.control.DropDown
        NAFXEditFieldLabel       matlab.ui.control.Label
        NAFX                     matlab.ui.control.NumericEditField
        VisAcuityEditFieldLabel  matlab.ui.control.Label
        vis_acuity               matlab.ui.control.NumericEditField
        SnellenEditFieldLabel    matlab.ui.control.Label
        Snellen                  matlab.ui.control.EditField
        Exact                    matlab.ui.control.CheckBox
    end

   
   methods (Access = public)
      
      function [snelstring1, snelstring2] = snellify(app,va)
         denom=20/va;
         
         % make pretty snellen output.
         basedenom = fix(denom/5)*5;
         remainder = denom-basedenom;
         if remainder == 0
            sneldenom = basedenom;
            tweak = '';
         elseif remainder < 2.5
            sneldenom = basedenom;
            tweak = '-';
         else
            sneldenom = basedenom+5;
            tweak = '+';
         end
         
         snelstring1 = ['20/' num2str(sneldenom) tweak];
         snelstring2 = ['20/' num2str(round(denom))   ];
         
      end
      
      function saveprefs(app)
         olddir=pwd;
         wpos=app.va2nafxUIFigure.Position;
         wpos=wpos(1:2);
         cd(findomprefs)
         save('va2nafx.mat', 'wpos')
         cd(olddir)
      end
      
   end
   

    methods (Access = private)

        % Code that executes after component creation
        function startup(app)
         set(0,'ShowHiddenHandles','on')
         ch = get(0,'Children');
         keeper=0;
         for i=1:length(ch)
            if strcmpi(ch(i).Name, 'va2nafx') ...
                  && strcmpi(ch(i).Tag,'set')
               keeper=i;
            end
         end
         if keeper>=1
            ML_W_switch('mlw')
            figure(ch(keeper))
            delete(app)
            return
         end
         
         olddir=pwd;
         cd(findomprefs)
         scrsize=get(0,'ScreenSize');
         if exist('va2nafx.mat','file')
            load va2nafx.mat
         else
            wpos = [scrsize(3)/2 scrsize(4)/2];
         end
         cd(olddir)
         cursize=app.va2nafxUIFigure.Position;
         newsize = [wpos(1:2) cursize(3) cursize(4)];
         app.va2nafxUIFigure.Position = newsize;
         app.va2nafxUIFigure.Tag = 'set';
         age_change(app);
        end

        % Value changed function: NAFX
        function nafx_change(app, event)
         temp=app.SubjectAge.UserData;
         nafx_slope=temp(1); nafx_offset=temp(2);
         nafx = app.NAFX.Value;
         va = (nafx*nafx_slope)-nafx_offset;
         app.vis_acuity.Value = va;
         exact_snel(app,va);
         saveprefs(app)
        end

        % Value changed function: vis_acuity
        function va_change(app, event)
         temp=app.SubjectAge.UserData;
         nafx_slope=temp(1); nafx_offset=temp(2);
         va = app.vis_acuity.Value;
         exact_snel(app,va);
         nafx = (va+nafx_offset)/nafx_slope;
         app.NAFX.Value = nafx;         
         if nafx>1
            disp('Warning: NAFX calculated to be >1.0.  This is not valid.')
            disp('The acuity may be better approximated by a different age')
            disp('population or it may exceed the 50% values for any age population.')
         end
         saveprefs(app)
         
        end

        % Callback function
        function snel_change(app, event)
         %va = app.Snellen.Value;
         %temp=app.SubjectAge.UserData;
         %nafx_slope=temp(1); nafx_offset=temp(2);
         %nafx = (va+nafx_offset)/nafx_slope;
         %app.NAFX.Value = nafx;
         
        end

        % Value changed function: SubjectAge
        function age_change(app, event)
         value = app.SubjectAge.Value;
         switch value
            case {'Under 6 y.o.'}
               nafx_slope = 0.8;
               nafx_offset = 0.0;    %% max = 20/25
               
            case {'6 to 12 y.o.', 'Over 60 y.o.'}
               nafx_slope = 1.0;
               nafx_offset = 0.0;    %% max = 20/20
               
            case {'12+ to 40 y.o.'}
               nafx_slope = 1.375;
               nafx_offset = 0.0;  %% max = 20/15+
               
            case {'40+ to 60 y.o.'}
               nafx_slope = 1.21;
               nafx_offset = 0.0;   %% max = 20/15-
               
            case {'Dog (age unimportant)'}
               nafx_slope = 0.286;
               nafx_offset = 0.0;  %% max = 20/70+
               
         end         
         app.SubjectAge.UserData = [nafx_slope,nafx_offset];
         nafx_change(app)
         saveprefs(app)
         
        end

        % Value changed function: Exact
        function exact_snel(app, event)
         exact = app.Exact.Value;
         va = app.vis_acuity.Value;
         [s1, s2] = snellify(app,va);
         if exact
            app.Snellen.Value=s2;
         else
            app.Snellen.Value=s1;
         end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.va2nafxUIFigure = uifigure;
            app.va2nafxUIFigure.Name = 'va2nafx';
            setAutoResize(app, app.va2nafxUIFigure, true)
            app.SubjectAgeDropDownLabel = uilabel(app.va2nafxUIFigure);
            app.SubjectAgeDropDownLabel.Text = 'Subject Age';
            app.SubjectAge = uidropdown(app.va2nafxUIFigure);
            app.SubjectAge.Items = {'Under 6 y.o.', '6 to 12 y.o.', '12+ to 40 y.o.', '40+ to 60 y.o.', 'Over 60 y.o.', 'Dog (age unimportant)'};
            app.SubjectAge.ValueChangedFcn = createCallbackFcn(app, @age_change, true);
            app.SubjectAge.Value = '12+ to 40 y.o.';
            app.NAFXEditFieldLabel = uilabel(app.va2nafxUIFigure);
            app.NAFXEditFieldLabel.Text = 'NAFX';
            app.NAFX = uieditfield(app.va2nafxUIFigure, 'numeric');
            app.NAFX.ValueChangedFcn = createCallbackFcn(app, @nafx_change, true);
            app.NAFX.ValueDisplayFormat = '%4.3f';
            app.VisAcuityEditFieldLabel = uilabel(app.va2nafxUIFigure);
            app.VisAcuityEditFieldLabel.Text = 'Vis Acuity';
            app.vis_acuity = uieditfield(app.va2nafxUIFigure, 'numeric');
            app.vis_acuity.ValueChangedFcn = createCallbackFcn(app, @va_change, true);
            app.vis_acuity.ValueDisplayFormat = '%4.3f';
            app.SnellenEditFieldLabel = uilabel(app.va2nafxUIFigure);
            app.SnellenEditFieldLabel.Text = 'Snellen';
            app.Snellen = uieditfield(app.va2nafxUIFigure, 'text');
            app.Snellen.Editable = 'off';
            app.Exact = uicheckbox(app.va2nafxUIFigure);
            app.Exact.ValueChangedFcn = createCallbackFcn(app, @exact_snel, true);
            app.Exact.Text = 'Exact';
        end
    end

    methods (Access = public)

        % Construct app
        function app = va2nafx_gui()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.va2nafxUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startup)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.va2nafxUIFigure)
        end
    end
end