classdef conv_bar < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        conv_barFig    matlab.ui.Figure
        IPDLabel       matlab.ui.control.Label
        IPD            matlab.ui.control.NumericEditField
        verge_angle_1  matlab.ui.control.NumericEditField
        LED1Label      matlab.ui.control.Label
        dist_1x        matlab.ui.control.NumericEditField
        verge_diopt_1  matlab.ui.control.NumericEditField
        DistxLabel     matlab.ui.control.Label
        VergLabel      matlab.ui.control.Label
        VergDLabel     matlab.ui.control.Label
        LED2Label      matlab.ui.control.Label
        dist_2x        matlab.ui.control.NumericEditField
        verge_angle_2  matlab.ui.control.NumericEditField
        verge_diopt_2  matlab.ui.control.NumericEditField
        LED3Label      matlab.ui.control.Label
        dist_3x        matlab.ui.control.NumericEditField
        verge_angle_3  matlab.ui.control.NumericEditField
        verge_diopt_3  matlab.ui.control.NumericEditField
        LED4Label      matlab.ui.control.Label
        dist_4x        matlab.ui.control.NumericEditField
        verge_angle_4  matlab.ui.control.NumericEditField
        verge_diopt_4  matlab.ui.control.NumericEditField
        LED5Label      matlab.ui.control.Label
        dist_5x        matlab.ui.control.NumericEditField
        verge_angle_5  matlab.ui.control.NumericEditField
        verge_diopt_5  matlab.ui.control.NumericEditField
        LED6Label      matlab.ui.control.Label
        dist_6x        matlab.ui.control.NumericEditField
        verge_angle_6  matlab.ui.control.NumericEditField
        verge_diopt_6  matlab.ui.control.NumericEditField
        LED7Label      matlab.ui.control.Label
        dist_7x        matlab.ui.control.NumericEditField
        verge_angle_7  matlab.ui.control.NumericEditField
        verge_diopt_7  matlab.ui.control.NumericEditField
        LED8Label      matlab.ui.control.Label
        dist_8x        matlab.ui.control.NumericEditField
        verge_angle_8  matlab.ui.control.NumericEditField
        verge_diopt_8  matlab.ui.control.NumericEditField
        dist_1y        matlab.ui.control.NumericEditField
        DistyLabel     matlab.ui.control.Label
        dist_2y        matlab.ui.control.NumericEditField
        dist_3y        matlab.ui.control.NumericEditField
        dist_4y        matlab.ui.control.NumericEditField
        dist_5y        matlab.ui.control.NumericEditField
        dist_6y        matlab.ui.control.NumericEditField
        dist_7y        matlab.ui.control.NumericEditField
        dist_8y        matlab.ui.control.NumericEditField
        export         matlab.ui.control.Button
        switch_1       matlab.ui.control.Switch
        switch_2       matlab.ui.control.Switch
        switch_3       matlab.ui.control.Switch
        switch_4       matlab.ui.control.Switch
        switch_5       matlab.ui.control.Switch
        switch_6       matlab.ui.control.Switch
        switch_7       matlab.ui.control.Switch
        switch_8       matlab.ui.control.Switch
        DECval         matlab.ui.control.NumericEditField
        LPTswitch      matlab.ui.control.Switch
        port1          matlab.ui.control.EditField
        port2          matlab.ui.control.EditField
    end

   
   properties (Access = public)
      lastval=0   % Description
      currport    % Built-in parallel port usually '0378' (hex)
      currindex = 1
   end
   
   methods (Access = private)
      
      function saveprefs(app,results)
         olddir=pwd;
         wpos=app.conv_barFig.Position;
         wpos=wpos(1:2);
         portaddr{1}=app.port1.Value;
         portaddr{2}=app.port2.Value;
         cd(findomprefs)
         save conv_bar.mat wpos results portaddr
         cd(olddir)
      end %%% saveprefs %%%
      
   end
   
   methods (Access = public)
      
      
   end
   

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
         app.conv_barFig.Visible='off';
         Eyelink('Shutdown');
         Eyelink('Initialize');
         pause(0.5)
         if ~Eyelink('IsConnected')
            disp('Could not connect to the Eyelink host.')
            return
         end
                  
         set(0,'ShowHiddenHandles','on')
         ch = get(0,'Children');
         keeper=0;
         for i=1:length(ch)
            if strcmpi(ch(i).Name, 'Convergence bar') ...
                  && strcmpi(ch(i).Tag,'control')
               keeper=i;
            end
         end
         if keeper>=1
            ML_W_switch('mlw')
            figure(ch(keeper))
            delete(app)
            return
         end
         
         olddir=pwd;
         cd(findomprefs)
         scrsize=get(0,'ScreenSize');
         wpos = [scrsize(3)/2 scrsize(4)/2];
         if exist('conv_bar.mat','file')
            load conv_bar.mat wpos portaddr results
         end
         
         if ~exist('results','var') || isempty(results)
            results = struct('dist_x',[], 'dist_y',[], ...
               'verg_ang',[],'verg_diopt',[],'ipd',[], ...
               'DECval',[],'sw',[] );
            results(1).dist_x=zeros(1,8);
            results(1).dist_y=zeros(1,8);
            results(1).verg_ang=zeros(1,8);
            results(1).verg_diopt=zeros(1,8);
            results(1).ipd=60;
            results(1).DECval=0;
            sw=cell(1,8); sw(:)={'0'};
            results(1).sw=sw;
            results(2)=results(1);
         end
         
         if exist('portaddr','var')
             app.port1.Value = portaddr{1};
             app.port2.Value = portaddr{2};
         end
         
         cd(olddir)
         cursize=app.conv_barFig.Position;
         newsize = [wpos(1:2) cursize(3) cursize(4)];
         app.conv_barFig.Position = newsize;
         app.conv_barFig.Color=[.94 .94 .94];
         app.conv_barFig.Tag = 'control';
         app.conv_barFig.Visible='on';
         
         % tag all the parameter input boxes w/their ID
         app.IPD.Tag = 'ipd';
         ci = app.currindex;
         app.IPD.Value = results(ci).ipd;
         
         for i=1:8
            num = num2str(i);
            temp=['dist_' num 'x'];
            app.(temp).Tag = temp;
            app.(temp).Value = results(ci).dist_x(i);
            temp=['dist_' num 'y'];
            app.(temp).Value = results(ci).dist_y(i);
            app.(temp).Tag = temp;
            temp=['verge_angle_' num];
            app.(temp).Tag = temp;
            app.(temp).Value = results(ci).verg_ang(i);
            temp=['verge_diopt_' num];
            app.(temp).Tag = temp;
            app.(temp).Value = results(ci).verg_diopt(i);
            temp=['switch_' num];
            app.(temp).Tag = temp;
            %app.(temp).Value = results(ci).sw(i);
         end
         
         results(ci).currport = app.currport;
         app.conv_barFig.UserData = results;
         app.conv_barFig.NextPlot = 'new';
         
         try
            parallelport(0,app.currport) % arg1:dec arg2:hex
         catch
            disp('Could not set parallel port.')
            return
         end
         %%% end of startup %%%
         
        end

        % Value changed function: IPD, dist_1x, dist_1y, dist_2x, 
        % dist_2y, dist_3x, dist_3y, dist_4x, dist_4y, dist_5x, dist_5y, 
        % dist_6x, dist_6y, dist_7x, dist_7y, dist_8x, dist_8y, 
        % verge_angle_1, verge_angle_2, verge_angle_3, verge_angle_4, 
        % verge_angle_5, verge_angle_6, verge_angle_7, verge_angle_8, 
        % verge_diopt_1, verge_diopt_2, verge_diopt_3, verge_diopt_4, 
        % verge_diopt_5, verge_diopt_6, verge_diopt_7, verge_diopt_8
        function recalc(app, event)
         results = app.conv_barFig.UserData;
         who_called = event.Source.Tag;
         row = str2double(who_called(isdigit(who_called)==1));
         if     strcmpi(who_called,'ipd'), col=0;
         elseif strcmpi(who_called(end),'x'),col=1;
         elseif strcmpi(who_called(end),'y'),col=2;
         elseif contains(who_called,'angle'),col=3;
         elseif contains(who_called,'diopt'),col=4;
         else,  error('cannot parse who_called for column')
         end
         
         % find which values (r,c) need to be updated
         if col==0
            start = 1;
            stop  = 8;
         else
            start = row;
            stop  = row;
         end
         
         ci  = app.currindex;
         ipd = app.IPD.Value;
         for i=start:stop
            num = num2str(i);
            % use col val to calc updated to OTHER cols
            % i.e., if you change either of the Distance values
            % then all changes will be made to the Vergence values
            if col==0 || col==1 || col==2 % IPD, d_x, or d_y
               temp=['dist_' num 'x'];
               results(ci).dist_x(i) = app.(temp).Value;
               temp=['dist_' num 'y'];
               results(ci).dist_y(i) = app.(temp).Value;
               dist_r = sqrt((results(ci).dist_x(i)).^2 + ...
                  (results(ci).dist_y(i))^2);
               
               % recalc convergence results
               dist = dist_r;
               v_ang = 2*atand(ipd/(2*dist));
               app.(['verge_angle_' num]).Value = v_ang;
               app.(['verge_diopt_' num]).Value = v_ang*1.75;
               results(ci).verg_ang(i)   = v_ang;
               results(ci).verg_diopt(i) = v_ang*1.75;
            end
            
            if col==0 || col==3 || col==4 % IPD, v_a_, or v_d_
               temp=['verge_angle_' num];
               results(ci).verg_ang(i) = app.(temp).Value;
               temp=['verge_diopt_' num];
               results(ci).verg_diopt(i) = app.(temp).Value;
               
               % change in v_angle or diopt: recalc distance results
               if col==3
                  v_ang = app.(['verge_angle_' num]).Value;
                  app.(['verge_diopt_' num]).Value = v_ang*1.75;
               elseif col == 4
                  % recalc distance results
                  diopt = app.(['verge_diopt_' num]).Value;
                  v_ang = diopt/1.75;
                  app.(['verge_angle_' num]).Value = v_ang;
               end
               dist = ipd/tand(v_ang);
               app.(['dist_' num 'x']).Value = dist;
               app.(['dist_' num 'y']).Value = 0;
               results(ci).dist_x(i) = dist;
               results(ci).dist_y(i) = 0;
            end
            results(ci).ipd = app.IPD.Value;
            results(ci).currport = app.currport;
            app.conv_barFig.UserData = results;
         end
         saveprefs(app,results)
         %%% end of recalc %%%
        end

        % Button pushed function: export
        function export_butt(app, event)
         results = app.conv_barFig.UserData;
         assignin('base','results',results)
         disp('''results'' saved to base workspace')
        end

        % Value changed function: switch_1, switch_2, switch_3, 
        % switch_4, switch_5, switch_6, switch_7, switch_8
        function LED_on_off(app, event)
         
         %sw = event.Source.Tag;
         %sw = sw(end);
         %sw = str2double(sw);
         %parallelport( dec2hex(2^(sw-1)) )
         ci=app.currindex;
         total = 0;
         for i=1:8
            if strcmp( app.(['switch_' num2str(i)]).Value, '1' )
               total = total + 2^(i-1);
            end
         end
         app.DECval.Value=total;
         app.lastval = total;
         try
            parallelport( total, app.currport )
         catch
            disp('Eyelink not detected.')
            return
         end
         results=app.conv_barFig.UserData;
         results(ci).DECval=app.DECval.Value;
         app.conv_barFig.UserData=results;
         saveprefs(app,results)
         %%% end of LED_on_off %%%
        end

        % Value changed function: DECval
        function num2bin(app, event)
         results=app.conv_barFig.UserData;
         newvalue = fix(app.DECval.Value);
         if newvalue>255 || newvalue<0
            app.DECval.Value = app.lastval;
            return
         end
         app.lastval=newvalue;
         b = de2bi(newvalue,8);
         ci=app.currindex;
         for i=1:8
            app.(['switch_' num2str(i)]).Value=num2str(b(i));
            results(ci).sw{i} = num2str(b(i));
         end
         results(ci).DECval=app.DECval.Value;
         app.conv_barFig.UserData=results;
         LED_on_off(app)
         %%% end of num2bin %%%
        end

        % Value changed function: LPTswitch
        function changeport(app, event)
         value = app.LPTswitch.Value;
         if strcmpi(value,'L1')
            app.currport=app.port1.Value; % built-in:'0378'
            app.currindex=1;
         elseif strcmpi(value,'L2')
            app.currport=app.port2.Value; % add-in:'E800','AF00'?
            app.currindex=2;
         end
         
         % should not change
         ci=app.currindex;
         results=app.conv_barFig.UserData;
         results(ci).IPD = app.IPD.Value;
         results(ci).currport=app.currport;
         results(ci).currindex=ci;
         app.conv_barFig.UserData=results;
         
         % swap the selected set of parameters back into controls
         app.IPD.Value = results(ci).IPD;
         app.DECval.Value = results(ci).DECval;
         for i=1:8
            distx = ['dist_' num2str(i) 'x'];
            app.(distx).Value = results(ci).dist_x(i);
            disty = ['dist_' num2str(i) 'y'];
            app.(disty).Value = results(ci).dist_y(i);
            va = ['verge_angle_' num2str(i)];
            app.(va).Value = results(ci).verg_ang(i);
            vd = ['verge_diopt_' num2str(i)];
            app.(vd).Value = results(ci).verg_diopt(i);
            sw = ['switch_' num2str(i)];
            app.(sw).Value = results(ci).sw{i};
         end
         
         LED_on_off(app)
         %%% end of changeport %%%
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.conv_barFig = uifigure;
            app.conv_barFig.Name = 'Convergence bar';
            app.IPDLabel = uilabel(app.conv_barFig);
            app.IPDLabel.Text = 'IPD';
            app.IPD = uieditfield(app.conv_barFig, 'numeric');
            app.IPD.ValueChangedFcn = createCallbackFcn(app, @recalc, true);
            app.IPD.Value = 60;
            app.verge_angle_1 = uieditfield(app.conv_barFig, 'numeric');
            app.verge_angle_1.ValueDisplayFormat = '%5.2f';
            app.verge_angle_1.ValueChangedFcn = createCallbackFcn(app, @recalc, true);
            app.LED1Label = uilabel(app.conv_barFig);
            app.LED1Label.Text = 'LED 1';
            app.dist_1x = uieditfield(app.conv_barFig, 'numeric');
            app.dist_1x.ValueChangedFcn = createCallbackFcn(app, @recalc, true);
            app.verge_diopt_1 = uieditfield(app.conv_barFig, 'numeric');
            app.verge_diopt_1.ValueDisplayFormat = '%5.2f';
            app.verge_diopt_1.ValueChangedFcn = createCallbackFcn(app, @recalc, true);
            app.DistxLabel = uilabel(app.conv_barFig);
            app.DistxLabel.Text = 'Dist (x)';
            app.VergLabel = uilabel(app.conv_barFig);
            app.VergLabel.Text = 'Verg (°)';
            app.VergDLabel = uilabel(app.conv_barFig);
            app.VergDLabel.Text = 'Verg (D)';
            app.LED2Label = uilabel(app.conv_barFig);
            app.LED2Label.Text = 'LED 2';
            app.dist_2x = uieditfield(app.conv_barFig, 'numeric');
            app.dist_2x.ValueChangedFcn = createCallbackFcn(app, @recalc, true);
            app.verge_angle_2 = uieditfield(app.conv_barFig, 'numeric');
            app.verge_angle_2.ValueDisplayFormat = '%5.2f';
            app.verge_angle_2.ValueChangedFcn = createCallbackFcn(app, @recalc, true);
            app.verge_diopt_2 = uieditfield(app.conv_barFig, 'numeric');
            app.verge_diopt_2.ValueDisplayFormat = '%5.2f';
            app.verge_diopt_2.ValueChangedFcn = createCallbackFcn(app, @recalc, true);
            app.LED3Label = uilabel(app.conv_barFig);
            app.LED3Label.Text = 'LED 3';
            app.dist_3x = uieditfield(app.conv_barFig, 'numeric');
            app.dist_3x.ValueChangedFcn = createCallbackFcn(app, @recalc, true);
            app.verge_angle_3 = uieditfield(app.conv_barFig, 'numeric');
            app.verge_angle_3.ValueDisplayFormat = '%5.2f';
            app.verge_angle_3.ValueChangedFcn = createCallbackFcn(app, @recalc, true);
            app.verge_diopt_3 = uieditfield(app.conv_barFig, 'numeric');
            app.verge_diopt_3.ValueDisplayFormat = '%5.2f';
            app.verge_diopt_3.ValueChangedFcn = createCallbackFcn(app, @recalc, true);
            app.LED4Label = uilabel(app.conv_barFig);
            app.LED4Label.Text = 'LED 4';
            app.dist_4x = uieditfield(app.conv_barFig, 'numeric');
            app.dist_4x.ValueChangedFcn = createCallbackFcn(app, @recalc, true);
            app.verge_angle_4 = uieditfield(app.conv_barFig, 'numeric');
            app.verge_angle_4.ValueDisplayFormat = '%5.2f';
            app.verge_angle_4.ValueChangedFcn = createCallbackFcn(app, @recalc, true);
            app.verge_diopt_4 = uieditfield(app.conv_barFig, 'numeric');
            app.verge_diopt_4.ValueDisplayFormat = '%5.2f';
            app.verge_diopt_4.ValueChangedFcn = createCallbackFcn(app, @recalc, true);
            app.LED5Label = uilabel(app.conv_barFig);
            app.LED5Label.Text = 'LED 5';
            app.dist_5x = uieditfield(app.conv_barFig, 'numeric');
            app.dist_5x.ValueChangedFcn = createCallbackFcn(app, @recalc, true);
            app.verge_angle_5 = uieditfield(app.conv_barFig, 'numeric');
            app.verge_angle_5.ValueDisplayFormat = '%5.2f';
            app.verge_angle_5.ValueChangedFcn = createCallbackFcn(app, @recalc, true);
            app.verge_diopt_5 = uieditfield(app.conv_barFig, 'numeric');
            app.verge_diopt_5.ValueDisplayFormat = '%5.2f';
            app.verge_diopt_5.ValueChangedFcn = createCallbackFcn(app, @recalc, true);
            app.LED6Label = uilabel(app.conv_barFig);
            app.LED6Label.Text = 'LED 6';
            app.dist_6x = uieditfield(app.conv_barFig, 'numeric');
            app.dist_6x.ValueChangedFcn = createCallbackFcn(app, @recalc, true);
            app.verge_angle_6 = uieditfield(app.conv_barFig, 'numeric');
            app.verge_angle_6.ValueDisplayFormat = '%5.2f';
            app.verge_angle_6.ValueChangedFcn = createCallbackFcn(app, @recalc, true);
            app.verge_diopt_6 = uieditfield(app.conv_barFig, 'numeric');
            app.verge_diopt_6.ValueDisplayFormat = '%5.2f';
            app.verge_diopt_6.ValueChangedFcn = createCallbackFcn(app, @recalc, true);
            app.LED7Label = uilabel(app.conv_barFig);
            app.LED7Label.Text = 'LED 7';
            app.dist_7x = uieditfield(app.conv_barFig, 'numeric');
            app.dist_7x.ValueChangedFcn = createCallbackFcn(app, @recalc, true);
            app.verge_angle_7 = uieditfield(app.conv_barFig, 'numeric');
            app.verge_angle_7.ValueDisplayFormat = '%5.2f';
            app.verge_angle_7.ValueChangedFcn = createCallbackFcn(app, @recalc, true);
            app.verge_diopt_7 = uieditfield(app.conv_barFig, 'numeric');
            app.verge_diopt_7.ValueDisplayFormat = '%5.2f';
            app.verge_diopt_7.ValueChangedFcn = createCallbackFcn(app, @recalc, true);
            app.LED8Label = uilabel(app.conv_barFig);
            app.LED8Label.Text = 'LED 8';
            app.dist_8x = uieditfield(app.conv_barFig, 'numeric');
            app.dist_8x.ValueChangedFcn = createCallbackFcn(app, @recalc, true);
            app.verge_angle_8 = uieditfield(app.conv_barFig, 'numeric');
            app.verge_angle_8.ValueDisplayFormat = '%5.2f';
            app.verge_angle_8.ValueChangedFcn = createCallbackFcn(app, @recalc, true);
            app.verge_diopt_8 = uieditfield(app.conv_barFig, 'numeric');
            app.verge_diopt_8.ValueDisplayFormat = '%5.2f';
            app.verge_diopt_8.ValueChangedFcn = createCallbackFcn(app, @recalc, true);
            app.dist_1y = uieditfield(app.conv_barFig, 'numeric');
            app.dist_1y.ValueChangedFcn = createCallbackFcn(app, @recalc, true);
            app.DistyLabel = uilabel(app.conv_barFig);
            app.DistyLabel.Text = 'Dist (y)';
            app.dist_2y = uieditfield(app.conv_barFig, 'numeric');
            app.dist_2y.ValueChangedFcn = createCallbackFcn(app, @recalc, true);
            app.dist_3y = uieditfield(app.conv_barFig, 'numeric');
            app.dist_3y.ValueChangedFcn = createCallbackFcn(app, @recalc, true);
            app.dist_4y = uieditfield(app.conv_barFig, 'numeric');
            app.dist_4y.ValueChangedFcn = createCallbackFcn(app, @recalc, true);
            app.dist_5y = uieditfield(app.conv_barFig, 'numeric');
            app.dist_5y.ValueChangedFcn = createCallbackFcn(app, @recalc, true);
            app.dist_6y = uieditfield(app.conv_barFig, 'numeric');
            app.dist_6y.ValueChangedFcn = createCallbackFcn(app, @recalc, true);
            app.dist_7y = uieditfield(app.conv_barFig, 'numeric');
            app.dist_7y.ValueChangedFcn = createCallbackFcn(app, @recalc, true);
            app.dist_8y = uieditfield(app.conv_barFig, 'numeric');
            app.dist_8y.ValueChangedFcn = createCallbackFcn(app, @recalc, true);
            app.export = uibutton(app.conv_barFig, 'push');
            app.export.ButtonPushedFcn = createCallbackFcn(app, @export_butt, true);
            app.export.Text = 'Export';
            app.switch_1 = uiswitch(app.conv_barFig, 'slider');
            app.switch_1.Items = {'0', '1'};
            app.switch_1.ValueChangedFcn = createCallbackFcn(app, @LED_on_off, true);
            app.switch_1.Value = '0';
            app.switch_2 = uiswitch(app.conv_barFig, 'slider');
            app.switch_2.Items = {'0', '1'};
            app.switch_2.ValueChangedFcn = createCallbackFcn(app, @LED_on_off, true);
            app.switch_2.Value = '0';
            app.switch_3 = uiswitch(app.conv_barFig, 'slider');
            app.switch_3.Items = {'0', '1'};
            app.switch_3.ValueChangedFcn = createCallbackFcn(app, @LED_on_off, true);
            app.switch_3.Value = '0';
            app.switch_4 = uiswitch(app.conv_barFig, 'slider');
            app.switch_4.Items = {'0', '1'};
            app.switch_4.ValueChangedFcn = createCallbackFcn(app, @LED_on_off, true);
            app.switch_4.Value = '0';
            app.switch_5 = uiswitch(app.conv_barFig, 'slider');
            app.switch_5.Items = {'0', '1'};
            app.switch_5.ValueChangedFcn = createCallbackFcn(app, @LED_on_off, true);
            app.switch_5.Value = '0';
            app.switch_6 = uiswitch(app.conv_barFig, 'slider');
            app.switch_6.Items = {'0', '1'};
            app.switch_6.ValueChangedFcn = createCallbackFcn(app, @LED_on_off, true);
            app.switch_6.Value = '0';
            app.switch_7 = uiswitch(app.conv_barFig, 'slider');
            app.switch_7.Items = {'0', '1'};
            app.switch_7.ValueChangedFcn = createCallbackFcn(app, @LED_on_off, true);
            app.switch_7.Value = '0';
            app.switch_8 = uiswitch(app.conv_barFig, 'slider');
            app.switch_8.Items = {'0', '1'};
            app.switch_8.ValueChangedFcn = createCallbackFcn(app, @LED_on_off, true);
            app.switch_8.Value = '0';
            app.DECval = uieditfield(app.conv_barFig, 'numeric');
            app.DECval.ValueChangedFcn = createCallbackFcn(app, @num2bin, true);
            app.LPTswitch = uiswitch(app.conv_barFig, 'slider');
            app.LPTswitch.Items = {'L1', 'L2'};
            app.LPTswitch.ValueChangedFcn = createCallbackFcn(app, @changeport, true);
            app.LPTswitch.Value = 'L1';
            app.port1 = uieditfield(app.conv_barFig, 'text');
            app.port1.Value = '0378';
            app.port2 = uieditfield(app.conv_barFig, 'text');
            app.port2.Value = '8e00';
        end
    end

    methods (Access = public)

        % Construct app
        function app = conv_bar

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.conv_barFig)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.conv_barFig)
        end
    end
end