classdef findsaccs_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        findsaccsUIFig                 matlab.ui.Figure
        FastLabel                      matlab.ui.control.Label
        gap_fp                         matlab.ui.control.NumericEditField
        SlowLabel                      matlab.ui.control.Label
        gap_sp                         matlab.ui.control.NumericEditField
        AmplrangeLoHiLabel             matlab.ui.control.Label
        range_lo                       matlab.ui.control.NumericEditField
        range_hi                       matlab.ui.control.NumericEditField
        DirectionDropDownLabel         matlab.ui.control.Label
        direction                      matlab.ui.control.DropDown
        SelectusingLabel               matlab.ui.control.Label
        vel_or_acc                     matlab.ui.control.DropDown
        ISIminLabel                    matlab.ui.control.Label
        swj_isi_min                    matlab.ui.control.NumericEditField
        DetectButton                   matlab.ui.control.Button
        SWJButton                      matlab.ui.control.Button
        channels                       matlab.ui.control.DropDown
        CurrentDropDownLabel           matlab.ui.control.Label
        availdata                      matlab.ui.control.DropDown
        AccButtonGroup                 matlab.ui.container.ButtonGroup
        use_acc_man                    matlab.ui.control.RadioButton
        use_acc_auto                   matlab.ui.control.RadioButton
        acc_on                         matlab.ui.control.NumericEditField
        acc_off                        matlab.ui.control.NumericEditField
        acc_auto                       matlab.ui.control.NumericEditField
        ThresholdOnOffAutodetectLabel  matlab.ui.control.Label
        VelButtonGroup                 matlab.ui.container.ButtonGroup
        use_vel_man                    matlab.ui.control.RadioButton
        use_vel_auto                   matlab.ui.control.RadioButton
        vel_auto                       matlab.ui.control.NumericEditField
        vel_off                        matlab.ui.control.NumericEditField
        vel_on                         matlab.ui.control.NumericEditField
        DOButton                       matlab.ui.control.Button
        settingsButton                 matlab.ui.control.StateButton
        lambda                         matlab.ui.control.NumericEditField
        MaxgapfillwidthsampsLabel      matlab.ui.control.Label
        SWJparametersLabel             matlab.ui.control.Label
        DOparametersLabel              matlab.ui.control.Label
        AutodetectLabel                matlab.ui.control.Label
        var1Label_2                    matlab.ui.control.Label
        do1                            matlab.ui.control.NumericEditField
        DeblinkCheckBox                matlab.ui.control.CheckBox
        DiffLevelLabel                 matlab.ui.control.Label
        diff_level                     matlab.ui.control.NumericEditField
        variancescaleLabel             matlab.ui.control.Label
        maxLabel                       matlab.ui.control.Label
        swj_isi_max                    matlab.ui.control.NumericEditField
    end

   
   properties (Access = public)
      EMDMwindow % Description
      emHand
      foundsaccs
      foundswj
   end
   
   methods (Static)
      
      function out = varmed(in)
         out = sqrt( nanmedian(in.^2)-(nanmedian(in) )^2 );
      end
      
   end
   
   methods (Access = private)
      
      function saveprefs(app)
         h = getparams(app);
         olddir=pwd;
         wpos=app.findsaccsUIFig.Position;
         wpos=wpos(1:2);
         cd(findomprefs)
         %save('findsaccsmgr.mat', 'wpos', 'h')
         save findsaccsmgr.mat wpos h
         cd(olddir)
      end
      
      function h = getparams(app)
         if app.use_acc_man.Value
            h.acc_on   = app.acc_on.Value;
            h.acc_off  = app.acc_off.Value;
         else
            h.acc_on  = app.acc_auto.Value;
            h.acc_off = app.acc_auto.Value;
         end
         if app.use_vel_man.Value
            h.vel_on   = app.vel_on.Value;
            h.vel_off  = app.vel_off.Value;
         else
            h.vel_on  = app.vel_auto.Value;
            h.vel_off = app.vel_auto.Value;
         end
         h.gap_fp   = app.gap_fp.Value;
         h.gap_sp   = app.gap_sp.Value;
         h.extend   = app.swj_isi_min.Value;
         h.range_hi = app.range_hi.Value;
         h.range_lo = app.range_lo.Value;
         h.range    = [h.range_lo h.range_hi];
         h.direction = app.direction.Value;
         temp = app.vel_or_acc.Value;
         h.vel_or_acc = lower(temp(1));
      end
      
      function refreshdata(app)
         if ~isvalid(app.emHand)
            return
         end
         names = app.emHand.loadednames;
         good  = find(~cellfun(@isempty,names),1);
         empties = find(cellfun(@isempty,names),1);
         if isempty(good)
            app.availdata.Items(1)={'* No Data Found'};
            app.availdata.Items(2)={'Get new data'};
            app.availdata.Items(3)={'Refresh'};
            return
         end
         
         lastselname=app.availdata.Value;
         lastsel=1;
         app.availdata.Items={};
         for z = 1:empties(1)-1
            if ~contains(names(z),'Refresh') && ...
                  ~contains(names(z),'Get new data')
               app.availdata.Items(z)=names(z);
               if strcmpi(names(z),lastselname)
                  lastsel=z;
               end
            else
               continue
            end
         end
         app.availdata.Items(end+1)={'Get new data'};
         app.availdata.Items(end+1)={'Refresh'};
         % select 1st item
         app.availdata.Value = app.availdata.Items{lastsel};
         update_thresh(app)
         saveprefs(app)
      end %function refreshdata
      
      
      function addfocus(app)
         webWindows = matlab.internal.webwindowmanager.instance.windowList;
         found=0;
         for i=1:length(webWindows)
            if strcmp(webWindows(i).Title,'Findsaccs control')
               found = i;
               win = webWindows(i);
               break
            end
         end
         if ~found
            disp('Cannot seem to find myself!')
            return
         end
         win.FocusGained = {@app.gainedfocus};
         win.FocusLost   = {@app.lostfocus};
      end
      
      function gainedfocus(app,a,b)
         refreshdata(app)
         %disp('FS focus')
      end
      function lostfocus(app,a,b)
         %disp('FS lost focus')
      end
      
      function update_thresh(app)
         % update autodetected vel,acc thresholds
         EMD = app.availdata.Value;
         temp=evalin('base',EMD);
         ch=app.channels.Value;
         samp_freq=temp.samp_freq;
         pos = temp.(ch).pos;
         vel = d2pt(pos,3,samp_freq);
         acc = d2pt(vel,3,samp_freq);
         temp1 = sqrt( nanmedian(acc.^2)-(nanmedian(acc) )^2 );
         temp2 = sqrt( nanmedian(vel.^2)-(nanmedian(vel) )^2 );
         app.acc_auto.Value = round(temp1*6);
         app.vel_auto.Value = round(temp2*6,1);
      end %func update_thresh
      
   end %methods(private)
   

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
         cd(findomprefs)
         scrsize=get(0,'ScreenSize');
         if exist('findsaccsmgr.mat','file')
            load findsaccsmgr.mat wpos
         else
            wpos = [scrsize(3)/2 scrsize(4)/2];
         end
         
         cursize=app.findsaccsUIFig.Position;
         % expanded or collapsed?
         if app.settingsButton.Value == 0
            cursize(3)=cursize(3)-200;
         end
         newsize = [wpos(1:2) cursize(3) cursize(4)];
         app.findsaccsUIFig.Position = newsize;
         app.findsaccsUIFig.NextPlot = 'new';
         app.findsaccsUIFig.Tag = 'control';
         
         
         app.EMDMwindow=findwind('EM Data');
         if app.EMDMwindow==-1
            datstat
            app.EMDMwindow=findwind('EM Data');
         end
         app.emHand = app.EMDMwindow.UserData;
         refreshdata(app);
         saveprefs(app)
         drawnow       % sooper-dooper important. otherwise the fs window won't be
         addfocus(app) % seeable to addfocus. Takes quite a while w/o drawnow
         
        end

        % Callback function
        function getdata(app, event)
         %temp=evalin('base','EMD');
         %app.dataname.Value = temp.filename;
        end

        % Button pushed function: DetectButton
        function detect(app, event)
         EMD = app.availdata.Value;
         temp=evalin('base',EMD);
         chan = app.channels.Value;
         pos=temp.(chan).pos;
         vel=d2pt(pos,3,temp.samp_freq);
         acc=d2pt(vel,3,temp.samp_freq);
         
         h = getparams(app);
         app.foundsaccs = findsaccs(pos, h.acc_on, h.vel_on, ...
            h.acc_off, h.vel_off, h.gap_fp, h.gap_sp, ...
            h.vel_or_acc, h.extend, h.range, h.direction);
         %app.findsaccsUIFig.UserData = app.foundsaccs;
         assignin('base','foundsaccs',app.foundsaccs)
         
         start=stripnan(app.foundsaccs.start);
         stop=stripnan(app.foundsaccs.stop);
         
         figure;
         title(['Findsaccs: ' nameclean(EMD)])
         xlabel('Sample number')
         ylabel('Pos (deg)')
         ax=gca; hold on; box
         plot(ax,pos,'Color','b','DisplayName','pos');
         plot(ax,start,pos(start),'bo')
         plot(ax,stop, pos(stop), 'b*')
         plot(ax,vel/10,'Color','g','DisplayName','vel');
         plot(ax,start,vel(start)/10,'go')
         plot(ax,stop, vel(stop)/10, 'g*')
         plot(ax,acc/1000,'Color','r','DisplayName','acc');
         plot(ax,start,acc(start)/1000,'ro')
         plot(ax,stop, acc(stop)/1000, 'r*')
         zoomtool
         saveprefs(app)
        end

        % Callback function
        function show(app, event)
         
        end

        % Value changed function: settingsButton
        function settings(app, event)
         value=app.settingsButton.Value;
         windpos=app.findsaccsUIFig.Position;
         
         if value==1
            windpos(3)=windpos(3)+200;
            app.findsaccsUIFig.Position=windpos;
         else
            windpos(3)=windpos(3)-200;
            app.findsaccsUIFig.Position=windpos;
         end
         
        end

        % Value changed function: availdata
        function getnewdata(app, event)
         temp = findwind('EM Data Manager');
         switch app.availdata.Value
            case {'Get new data'}
               if temp ~= -1
                  app.EMDMwindow = temp;
                  app.EMDMwindow.Visible = 'off';
                  app.EMDMwindow.Visible = 'on';
               else
                  datstat
               end
               % needed some way to wait until new data is loaded
               % before trying to refresh menu.
               % can we use java focus hack to auto refresh when
               % findsaccs window is brought back to foreground?
               % NOPE. UIFigure windows don't have Java Frame?
               % They DO have "webWindows". See 'addfocus' above
               refreshdata(app);
            case {'Refresh'}
               refreshdata(app)
            otherwise
               refreshdata(app)
         end
        end

        % Button pushed function: SWJButton
        function calc_swj(app, event)
         EMD  = app.availdata.Value;
         temp = evalin('base',EMD);
         chan = app.channels.Value;
         pos  = temp.(chan).pos;
         samp_freq=temp.samp_freq;
         
         h = getparams(app);
         % SWJ-specific settings
         swj_vel_on  = min(10,h.vel_on);
         swj_vel_off = min(10,h.vel_off);
         swj_range   = [0.1, 5];
         app.foundsaccs = findsaccs(pos, h.acc_on, swj_vel_on, ...
            h.acc_off, swj_vel_off, h.gap_fp, h.gap_sp, ...
            h.vel_or_acc, h.extend, swj_range, 'Both');
         
         app.foundswj=find_swj(app.foundsaccs, pos, samp_freq);
         if isempty(app.swj)
            disp('No SWJs were found.')
            return
         end
         assignin('base','swj',app.foundswj)
         figure; hold on; box
         ax=gca;
         title('SWJ detection test')
         plot(ax,pos,'b')
         allswj=[];
         for jj=1:length(app.swj.start)
            %allswj=[allswj, swj.start(jj):swj.stop(jj)];
            allswj=horzcat(allswj, app.swj.start(jj):app.swj.stop(jj));
         end
         temp=NaN(size(pos));
         temp(allswj)=pos(allswj);
         plot(ax,temp,'r')
         fs_start=stripnan(app.foundsaccs.start);
         fs_stop =stripnan(app.foundsaccs.stop);
         plot(ax,fs_start,pos(fs_start),'bo')
         plot(ax,fs_stop, pos(fs_stop), 'b*')
        end

        % Value changed function: channels
        function change_chan(app, event)
         update_thresh(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.findsaccsUIFig = uifigure;
            app.findsaccsUIFig.Name = 'Findsaccs control';
            app.FastLabel = uilabel(app.findsaccsUIFig);
            app.FastLabel.Text = 'Fast ϕ';
            app.gap_fp = uieditfield(app.findsaccsUIFig, 'numeric');
            app.gap_fp.Limits = [0 Inf];
            app.gap_fp.Value = 10;
            app.SlowLabel = uilabel(app.findsaccsUIFig);
            app.SlowLabel.Text = 'Slow ϕ';
            app.gap_sp = uieditfield(app.findsaccsUIFig, 'numeric');
            app.gap_sp.Limits = [0 Inf];
            app.gap_sp.Value = 10;
            app.AmplrangeLoHiLabel = uilabel(app.findsaccsUIFig);
            app.AmplrangeLoHiLabel.Text = 'Ampl range (Lo Hi)';
            app.range_lo = uieditfield(app.findsaccsUIFig, 'numeric');
            app.range_lo.Limits = [0 Inf];
            app.range_lo.Value = 0.1;
            app.range_hi = uieditfield(app.findsaccsUIFig, 'numeric');
            app.range_hi.Limits = [0 Inf];
            app.range_hi.Value = 40;
            app.DirectionDropDownLabel = uilabel(app.findsaccsUIFig);
            app.DirectionDropDownLabel.Text = 'Direction';
            app.direction = uidropdown(app.findsaccsUIFig);
            app.direction.Items = {'Left', 'Right', 'Both'};
            app.direction.Value = 'Both';
            app.SelectusingLabel = uilabel(app.findsaccsUIFig);
            app.SelectusingLabel.Text = 'Select using';
            app.vel_or_acc = uidropdown(app.findsaccsUIFig);
            app.vel_or_acc.Items = {'Acceleration', 'Velocity', 'Both'};
            app.vel_or_acc.Value = 'Velocity';
            app.ISIminLabel = uilabel(app.findsaccsUIFig);
            app.ISIminLabel.Text = 'ISI: min';
            app.swj_isi_min = uieditfield(app.findsaccsUIFig, 'numeric');
            app.swj_isi_min.Limits = [0 Inf];
            app.swj_isi_min.Value = 5;
            app.DetectButton = uibutton(app.findsaccsUIFig, 'push');
            app.DetectButton.ButtonPushedFcn = createCallbackFcn(app, @detect, true);
            app.DetectButton.Text = 'Detect';
            app.SWJButton = uibutton(app.findsaccsUIFig, 'push');
            app.SWJButton.ButtonPushedFcn = createCallbackFcn(app, @calc_swj, true);
            app.SWJButton.Text = 'SWJ';
            app.channels = uidropdown(app.findsaccsUIFig);
            app.channels.Items = {'rh', 'lh', 'rv', 'lv'};
            app.channels.ValueChangedFcn = createCallbackFcn(app, @change_chan, true);
            app.channels.Value = 'rh';
            app.CurrentDropDownLabel = uilabel(app.findsaccsUIFig);
            app.CurrentDropDownLabel.Text = 'Current:';
            app.availdata = uidropdown(app.findsaccsUIFig);
            app.availdata.Items = {'Get new data'};
            app.availdata.ValueChangedFcn = createCallbackFcn(app, @getnewdata, true);
            app.availdata.Value = 'Get new data';
            app.AccButtonGroup = uibuttongroup(app.findsaccsUIFig);
            app.use_acc_man = uiradiobutton(app.AccButtonGroup);
            app.use_acc_man.Text = 'Acc:';
            app.use_acc_auto = uiradiobutton(app.AccButtonGroup);
            app.use_acc_auto.Text = '';
            app.use_acc_auto.Value = true;
            app.acc_on = uieditfield(app.AccButtonGroup, 'numeric');
            app.acc_on.Limits = [0 Inf];
            app.acc_on.Value = 800;
            app.acc_off = uieditfield(app.AccButtonGroup, 'numeric');
            app.acc_off.Limits = [0 Inf];
            app.acc_off.Value = 800;
            app.acc_auto = uieditfield(app.AccButtonGroup, 'numeric');
            app.acc_auto.Limits = [0 Inf];
            app.acc_auto.Value = 800;
            app.ThresholdOnOffAutodetectLabel = uilabel(app.findsaccsUIFig);
            app.ThresholdOnOffAutodetectLabel.Text = 'Threshold     On          Off      Autodetect';
            app.VelButtonGroup = uibuttongroup(app.findsaccsUIFig);
            app.use_vel_man = uiradiobutton(app.VelButtonGroup);
            app.use_vel_man.Text = 'Vel:';
            app.use_vel_auto = uiradiobutton(app.VelButtonGroup);
            app.use_vel_auto.Text = '';
            app.use_vel_auto.Value = true;
            app.vel_auto = uieditfield(app.VelButtonGroup, 'numeric');
            app.vel_auto.Limits = [0 Inf];
            app.vel_auto.Value = 40;
            app.vel_off = uieditfield(app.VelButtonGroup, 'numeric');
            app.vel_off.Limits = [0 Inf];
            app.vel_off.Value = 40;
            app.vel_on = uieditfield(app.VelButtonGroup, 'numeric');
            app.vel_on.Limits = [0 Inf];
            app.vel_on.Value = 40;
            app.DOButton = uibutton(app.findsaccsUIFig, 'push');
            app.DOButton.Text = 'D.O.';
            app.settingsButton = uibutton(app.findsaccsUIFig, 'state');
            app.settingsButton.ValueChangedFcn = createCallbackFcn(app, @settings, true);
            app.settingsButton.Text = '<>';
            app.lambda = uieditfield(app.findsaccsUIFig, 'numeric');
            app.lambda.Limits = [0 Inf];
            app.lambda.Value = 5;
            app.MaxgapfillwidthsampsLabel = uilabel(app.findsaccsUIFig);
            app.MaxgapfillwidthsampsLabel.Text = 'Max gap fill width (#samps)';
            app.SWJparametersLabel = uilabel(app.findsaccsUIFig);
            app.SWJparametersLabel.Text = 'SWJ parameters';
            app.DOparametersLabel = uilabel(app.findsaccsUIFig);
            app.DOparametersLabel.Text = 'DO parameters';
            app.AutodetectLabel = uilabel(app.findsaccsUIFig);
            app.AutodetectLabel.Text = 'Autodetect';
            app.var1Label_2 = uilabel(app.findsaccsUIFig);
            app.var1Label_2.Text = 'var1';
            app.do1 = uieditfield(app.findsaccsUIFig, 'numeric');
            app.do1.Limits = [0 Inf];
            app.do1.Value = 5;
            app.DeblinkCheckBox = uicheckbox(app.findsaccsUIFig);
            app.DeblinkCheckBox.Text = 'Deblink';
            app.DiffLevelLabel = uilabel(app.findsaccsUIFig);
            app.DiffLevelLabel.Text = 'Diff. Level';
            app.diff_level = uieditfield(app.findsaccsUIFig, 'numeric');
            app.diff_level.Limits = [0 Inf];
            app.diff_level.Value = 3;
            app.variancescaleLabel = uilabel(app.findsaccsUIFig);
            app.variancescaleLabel.Text = 'variance scale';
            app.maxLabel = uilabel(app.findsaccsUIFig);
            app.maxLabel.Text = 'max';
            app.swj_isi_max = uieditfield(app.findsaccsUIFig, 'numeric');
            app.swj_isi_max.Limits = [0 Inf];
            app.swj_isi_max.Value = 5;
        end
    end

    methods (Access = public)

        % Construct app
        function app = findsaccs_gui

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.findsaccsUIFig)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.findsaccsUIFig)
        end
    end
end