classdef univ_pos2ang < matlab.apps.AppBase

   % Properties that correspond to app components
   properties (Access = public)
      pos2angUI            matlab.ui.Figure
      UIAxes               matlab.ui.control.UIAxes
      DistsEditFieldLabel  matlab.ui.control.Label
      verge_table          matlab.ui.control.Table
      Panel                matlab.ui.container.Panel
      XLabel_6             matlab.ui.control.Label
      TgtAngle_LE_x        matlab.ui.control.NumericEditField
      YLabel_2             matlab.ui.control.Label
      TgtAng_LE_y          matlab.ui.control.NumericEditField
      XLabel_7             matlab.ui.control.Label
      TgtAngle_RE_x        matlab.ui.control.NumericEditField
      YLabel_4             matlab.ui.control.Label
      TgtAng_RE_y          matlab.ui.control.NumericEditField
      dist_list            matlab.ui.control.EditField
      maxLabel             matlab.ui.control.Label
      max_dist             matlab.ui.control.NumericEditField
      tgt_width            matlab.ui.control.NumericEditField
      HLabel               matlab.ui.control.Label
      tgt_height           matlab.ui.control.EditField
      WLabel               matlab.ui.control.Label
      Panel_RE             matlab.ui.container.Panel
      RightEyeLabel        matlab.ui.control.Label
      TgtDistRLabel_2      matlab.ui.control.Label
      TgtDist_RE_rad       matlab.ui.control.NumericEditField
      Label                matlab.ui.control.Label
      TgtAngle_RE_th       matlab.ui.control.NumericEditField
      Label_2              matlab.ui.control.Label
      TgtAng_RE_phi        matlab.ui.control.NumericEditField
      YEditFieldLabel_3    matlab.ui.control.Label
      TgtDist_RE_y         matlab.ui.control.NumericEditField
      XLabel_3             matlab.ui.control.Label
      TgtDist_RE_x         matlab.ui.control.NumericEditField
      Panel_LE             matlab.ui.container.Panel
      LeftEyeLabel         matlab.ui.control.Label
      TgtDistRLabel        matlab.ui.control.Label
      TgtDist_LE_rad       matlab.ui.control.NumericEditField
      Label_4              matlab.ui.control.Label
      TgtAngle_LE_th       matlab.ui.control.NumericEditField
      Label_3              matlab.ui.control.Label
      TgtAng_LE_phi        matlab.ui.control.NumericEditField
      XLabel_4             matlab.ui.control.Label
      TgtDist_LE_x         matlab.ui.control.NumericEditField
      YLabel_3             matlab.ui.control.Label
      TgtDist_LE_y         matlab.ui.control.NumericEditField
      ExportButt           matlab.ui.control.Button
      minEditFieldLabel    matlab.ui.control.Label
      min_dist             matlab.ui.control.NumericEditField
      IPDEditFieldLabel    matlab.ui.control.Label
      IPD                  matlab.ui.control.NumericEditField
      tgt_angle            matlab.ui.control.NumericEditField
      TgtLabel             matlab.ui.control.Label
      CalcButt             matlab.ui.control.Button
      tgt_dist             matlab.ui.control.NumericEditField
      distLabel            matlab.ui.control.Label
      IPDsLabel            matlab.ui.control.Label
      ipd_list             matlab.ui.control.EditField
   end

   
   methods (Access = private)
      
      function saveprefs(app)
         olddir=pwd;
         wpos=app.pos2angUI.Position;
         wpos=wpos(1:2);
         dists=app.dist_list.Value;
         ipds=app.ipd_list.Value;
         cd(findomprefs)
         save('pos_ang.mat', 'wpos','dists','ipds')
         cd(olddir)
      end
      
   end
   

   % Callbacks that handle component events
   methods (Access = private)

      % Code that executes after component creation
      function startupFcn(app)
         set(0,'ShowHiddenHandles','on')
         ch = get(0,'Children');
         keeper=0;
         for i=1:length(ch)
            if strcmpi(ch(i).Name, 'pos_2_ang') ...
                  && strcmpi(ch(i).Tag,'control')
            keeper=i;
            end
         end
         if keeper>=1
            ML_W_switch('mlw')
            figure(ch(keeper))
            delete(app)
            return
         end
         
         olddir=pwd;
         cd(findomprefs)
         scrsize=get(0,'ScreenSize');
         try
            load('pos_ang.mat','wpos','dists','ipds');
         catch
            wpos = [scrsize(3)/2 scrsize(4)/2];
            dists = '200, 330, 550, 750, 1500, 2440';
            ipds  = '30 35 40 45 50 55, 60 65';
         end
         cd(olddir)
         
         cursize=app.pos2angUI.Position;
         newsize = [wpos(1:2) cursize(3) cursize(4)];
         app.dist_list.Value=dists;
         app.ipd_list.Value=ipds;
         app.pos2angUI.Position = newsize;
         app.pos2angUI.Tag = 'control';
         app.pos2angUI.NextPlot = 'new';
         app.pos2angUI.Color=[.94 .94 .94];
         recalc(app)
         
      end

      % Value changed function: TgtDist_RE_rad
      function disp_updated(app, event)
         %dist = app.TgtDist_RE_rad.Value;
         %tgt_wid = app.tgt_width.Value;
         %vis_ang = 2*atand((tgt_wid*0.5)/dist);
         %app.vis_angle.Value = vis_ang;
         %app.vis_diopt.Value = vis_ang*1.75;
         saveprefs(app)
         
      end

      % Button pushed function: CalcButt
      function recalc(app, event)
         try
            dists=str2num(app.dist_list.Value); %#ok<ST2NM>
            ipd=str2num(app.ipd_list.Value); %#ok<ST2NM>
         catch
            disp('Numbers, spaces, or commas ONLY.')
            beep
            return
         end
         numdists=length(dists);
         data=nan(length(ipd),numdists+1);
         data(:,1)=ipd;

         app.verge_table.ColumnName='';
         app.verge_table.ColumnName{1}='IPD';
         app.verge_table.ColumnFormat{1}='short';
         for ii=1:numdists
            app.verge_table.ColumnName{ii+1}=num2str(dists(ii));
            app.verge_table.ColumnFormat{ii+1}='bank';
         end
         
         for jj=1:length(ipd)
            %calculate the binocular vergence
            v_ang = 2*atand(ipd(jj)./(2*dists));
            data(jj,2:numdists+1)=round(v_ang,2);
            %app.verge_table.Data(jj,2:numdists+1)=round(v_ang,2);
         end
         app.verge_table.Data=data;
         %app.verge_table.Data(:,2:numdists+1)=data(:,2:numdists+1);
         saveprefs(app)
      end

      % Button pushed function: ExportButt
      function export_data(app, event)
         pause(0.5)
         ML_W_switch('ml')
         [fn,pn]=uiputfile('*.*','Save vergence angle table as:');
         if fn==-1,disp('Save canceled.');return;end
         [~,fn,~]=fileparts(fn);
         olddir=pwd;
         cd(pn)
         t=array2table(app.verge_table.Data);
         t.Properties.VariableNames = app.verge_table.ColumnName;
         writetable(t,fn, 'FileType', 'spreadsheet')
         cd(olddir)
         pause(0.5)
         ML_W_switch('mlw')
      end
   end

   % Component initialization
   methods (Access = private)

      % Create UIFigure and components
      function createComponents(app)
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         app.pos2angUI = uifigure('Visible', 'off');
         app.pos2angUI.Name = 'pos_2_ang';
         app.UIAxes = uiaxes(app.pos2angUI);
         title(app.UIAxes, 'IPD, Angle')
         xlabel(app.UIAxes, 'Tgt Dist from Subj')
         ylabel(app.UIAxes, 'Dist from midline')
         zlabel(app.UIAxes, 'Z')
         app.UIAxes.XLim = [0 1500];
         app.UIAxes.YLim = [0 30];
         app.DistsEditFieldLabel = uilabel(app.pos2angUI);
         app.DistsEditFieldLabel.Text = '0° Dists:';
         app.verge_table = uitable(app.pos2angUI);
         app.verge_table.ColumnName = {'IPD'; '200'; '330'; '550'; '750'; '1500'; '2440'};
         app.verge_table.RowName = {};
         app.Panel = uipanel(app.pos2angUI);
         app.Panel.Title = 'Panel';
         app.XLabel_6 = uilabel(app.Panel);
         app.XLabel_6.Enable = 'off';
         app.XLabel_6.Text = 'X';
         app.TgtAngle_LE_x = uieditfield(app.Panel, 'numeric');
         app.TgtAngle_LE_x.Enable = 'off';
         app.YLabel_2 = uilabel(app.Panel);
         app.YLabel_2.Enable = 'off';
         app.YLabel_2.Text = 'Y';
         app.TgtAng_LE_y = uieditfield(app.Panel, 'numeric');
         app.TgtAng_LE_y.Enable = 'off';
         app.XLabel_7 = uilabel(app.Panel);
         app.XLabel_7.Enable = 'off';
         app.XLabel_7.Text = 'X';
         app.TgtAngle_RE_x = uieditfield(app.Panel, 'numeric');
         app.TgtAngle_RE_x.Enable = 'off';
         app.YLabel_4 = uilabel(app.Panel);
         app.YLabel_4.Enable = 'off';
         app.YLabel_4.Text = 'Y';
         app.TgtAng_RE_y = uieditfield(app.Panel, 'numeric');
         app.TgtAng_RE_y.Enable = 'off';
         app.dist_list = uieditfield(app.pos2angUI, 'text');
         app.dist_list.Tooltip = {'Separate entries with comma or space'};
         app.dist_list.Value = '200, 330, 550, 750, 1500, 2440';
         app.maxLabel = uilabel(app.pos2angUI);
         app.maxLabel.Text = {'max'; ''};
         app.max_dist = uieditfield(app.pos2angUI, 'numeric');
         app.max_dist.Value = 1500;
         app.tgt_width = uieditfield(app.pos2angUI, 'numeric');
         app.HLabel = uilabel(app.pos2angUI);
         app.HLabel.Text = 'H';
         app.tgt_height = uieditfield(app.pos2angUI, 'text');
         app.tgt_height.Value = '0';
         app.WLabel = uilabel(app.pos2angUI);
         app.WLabel.Text = 'W';
         app.Panel_RE = uipanel(app.pos2angUI);
         app.RightEyeLabel = uilabel(app.Panel_RE);
         app.RightEyeLabel.Text = {'Right'; 'Eye'};
         app.TgtDistRLabel_2 = uilabel(app.Panel_RE);
         app.TgtDistRLabel_2.Text = 'Tgt Dist:   R';
         app.TgtDist_RE_rad = uieditfield(app.Panel_RE, 'numeric');
         app.TgtDist_RE_rad.ValueChangedFcn = createCallbackFcn(app, @disp_updated, true);
         app.Label = uilabel(app.Panel_RE);
         app.Label.Text = 'θ';
         app.TgtAngle_RE_th = uieditfield(app.Panel_RE, 'numeric');
         app.Label_2 = uilabel(app.Panel_RE);
         app.Label_2.Text = 'ϕ';
         app.TgtAng_RE_phi = uieditfield(app.Panel_RE, 'numeric');
         app.YEditFieldLabel_3 = uilabel(app.Panel_RE);
         app.YEditFieldLabel_3.Text = 'Y';
         app.TgtDist_RE_y = uieditfield(app.Panel_RE, 'numeric');
         app.XLabel_3 = uilabel(app.Panel_RE);
         app.XLabel_3.Text = 'X';
         app.TgtDist_RE_x = uieditfield(app.Panel_RE, 'numeric');
         app.Panel_LE = uipanel(app.pos2angUI);
         app.LeftEyeLabel = uilabel(app.Panel_LE);
         app.LeftEyeLabel.Text = {'Left'; 'Eye'};
         app.TgtDistRLabel = uilabel(app.Panel_LE);
         app.TgtDistRLabel.Text = 'Tgt Dist:   R';
         app.TgtDist_LE_rad = uieditfield(app.Panel_LE, 'numeric');
         app.Label_4 = uilabel(app.Panel_LE);
         app.Label_4.Text = 'θ';
         app.TgtAngle_LE_th = uieditfield(app.Panel_LE, 'numeric');
         app.Label_3 = uilabel(app.Panel_LE);
         app.Label_3.Text = 'ϕ';
         app.TgtAng_LE_phi = uieditfield(app.Panel_LE, 'numeric');
         app.XLabel_4 = uilabel(app.Panel_LE);
         app.XLabel_4.Text = 'X';
         app.TgtDist_LE_x = uieditfield(app.Panel_LE, 'numeric');
         app.YLabel_3 = uilabel(app.Panel_LE);
         app.YLabel_3.Text = 'Y';
         app.TgtDist_LE_y = uieditfield(app.Panel_LE, 'numeric');
         app.ExportButt = uibutton(app.pos2angUI, 'push');
         app.ExportButt.ButtonPushedFcn = createCallbackFcn(app, @export_data, true);
         app.ExportButt.Text = 'Export';
         app.minEditFieldLabel = uilabel(app.pos2angUI);
         app.minEditFieldLabel.Text = 'min';
         app.min_dist = uieditfield(app.pos2angUI, 'numeric');
         app.IPDEditFieldLabel = uilabel(app.pos2angUI);
         app.IPDEditFieldLabel.Text = 'IPD';
         app.IPD = uieditfield(app.pos2angUI, 'numeric');
         app.IPD.Value = 60;
         app.tgt_angle = uieditfield(app.pos2angUI, 'numeric');
         app.TgtLabel = uilabel(app.pos2angUI);
         app.TgtLabel.Text = 'Tgt θ';
         app.CalcButt = uibutton(app.pos2angUI, 'push');
         app.CalcButt.ButtonPushedFcn = createCallbackFcn(app, @recalc, true);
         app.CalcButt.Text = 'Calc';
         app.tgt_dist = uieditfield(app.pos2angUI, 'numeric');
         app.distLabel = uilabel(app.pos2angUI);
         app.distLabel.Text = 'dist';
         app.IPDsLabel = uilabel(app.pos2angUI);
         app.IPDsLabel.Text = 'IPDs';
         app.ipd_list = uieditfield(app.pos2angUI, 'text');
         app.ipd_list.Tooltip = {'Separate entries with comma or space'};
         app.ipd_list.Value = '30, 35, 40, 45,50, 55, 60, 65';
         % Show the figure after all components are created
         app.pos2angUI.Visible = 'on';
      end
   end

   % App creation and deletion
   methods (Access = public)

      % Construct app
      function app = univ_pos2ang

         % Create UIFigure and components
         createComponents(app)

         % Register the app with App Designer
         registerApp(app, app.pos2angUI)

         % Execute the startup function
         runStartupFcn(app, @startupFcn)

         if nargout == 0
            clear app
         end
      end

      % Code that executes before app deletion
      function delete(app)

         % Delete UIFigure when app is deleted
         delete(app.pos2angUI)
      end
   end
end