classdef vis_ang < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        vis_angUIFigure  matlab.ui.Figure
        wid_lock         matlab.ui.control.CheckBox
        dist_lock        matlab.ui.control.CheckBox
        tgt_widthLabel   matlab.ui.control.Label
        tgt_width        matlab.ui.control.NumericEditField
        distanceLabel    matlab.ui.control.Label
        distance         matlab.ui.control.NumericEditField
        vis_angleLabel   matlab.ui.control.Label
        vis_angle        matlab.ui.control.NumericEditField
        vis_dioptLabel   matlab.ui.control.Label
        vis_diopt        matlab.ui.control.NumericEditField
    end


    methods (Access = private)
       function saveprefs(app)
          olddir=pwd;
          wpos=app.vis_angUIFigure.Position;
          wpos=wpos(1:2);
          cd(findomprefs)
          save('vis_ang.mat', 'wpos')
          cd(olddir)
       end
       
    end


    methods (Access = private)

        % Code that executes after component creation
        function startup(app)
         set(0,'ShowHiddenHandles','on')
         ch = get(0,'Children');
         keeper=0;
         for i=1:length(ch)
            if strcmpi(ch(i).Name, 'vis_ang') ...
                  && strcmpi(ch(i).Tag,'set')
               keeper=i;
            end
         end
         if keeper>=1
            ML_W_switch('mlw')
            figure(ch(keeper))
            delete(app)
            return
         end
         
         olddir=pwd;
         cd(findomprefs)
         scrsize=get(0,'ScreenSize');
         if exist('vis_ang.mat','file')
            load vis_ang.mat
         else
            wpos = [scrsize(3)/2 scrsize(4)/2];
         end
         cd(olddir)
         cursize=app.vis_angUIFigure.Position;
         newsize = [wpos(1:2) cursize(3) cursize(4)];
         app.vis_angUIFigure.Position = newsize;
         app.vis_angUIFigure.Tag = 'set';            
         app.vis_angUIFigure.NextPlot = 'new';            
        end

        % Value changed function: wid_lock
        function wid_tggl(app, event)
            value = app.wid_lock.Value;
            if value
                app.dist_lock.Value = 0;
            end
        end

        % Value changed function: dist_lock
        function dist_tggl(app, event)
            value = app.dist_lock.Value;
            if value
                app.wid_lock = 0;
            end                        
        end

        % Value changed function: tgt_width
        function tgt_width_updated(app, event)
         dist    = app.distance.Value;
         tgt_wid = app.tgt_width.Value;
         vis_ang = 2*atand((tgt_wid*0.5)/dist);
         app.vis_angle.Value = vis_ang;
         app.vis_diopt.Value = vis_ang*1.75;
         saveprefs(app)            
        end

        % Value changed function: distance
        function dist_updated(app, event)
         tgt_wid = app.tgt_width.Value;
         dist    = app.distance.Value;
         vis_ang = 2*atand((tgt_wid*0.5)/dist);
         app.vis_angle.Value = vis_ang;
         app.vis_diopt.Value = vis_ang*1.75;
         saveprefs(app)            
        end

        % Value changed function: vis_angle
        function v_updated(app, event)
         vis_ang = app.vis_angle.Value;
         if app.dist_lock.Value
            dist = app.distance.Value;
            tgt_wid = dist/(tand(vis_ang));
            app.tgt_wid.Value = tgt_wid;
         elseif app.wid_lock.Value
            tgt_wid = app.tgt_width.Value;
            dist    = tgt_wid/(tand(vis_ang));
            app.distance.Value = dist;
         end
         app.vis_diopt.Value = vis_ang*1.75;
        end

        % Value changed function: vis_diopt
        function diopt_updated(app, event)
         vis_ang = app.vis_diopt.Value / 1.75;
         if app.dist_lock.Value
            dist = app.distance.Value;
            tgt_wid = dist/(tand(vis_ang));
            app.tgt_wid.Value = tgt_wid;
         elseif app.wid_lock.Value
            tgt_wid = app.tgt_width.Value;
            dist    = tgt_wid/(tand(vis_ang));
            app.distance.Value = dist;
         end
         app.vis_angle.Value = vis_ang;            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.vis_angUIFigure = uifigure;
            app.vis_angUIFigure.Name = 'vis_ang';
            app.wid_lock = uicheckbox(app.vis_angUIFigure);
            app.wid_lock.ValueChangedFcn = createCallbackFcn(app, @wid_tggl, true);
            app.wid_lock.Text = 'Lock';
            app.dist_lock = uicheckbox(app.vis_angUIFigure);
            app.dist_lock.ValueChangedFcn = createCallbackFcn(app, @dist_tggl, true);
            app.dist_lock.Text = 'Lock';
            app.tgt_widthLabel = uilabel(app.vis_angUIFigure);
            app.tgt_widthLabel.Text = 'Tgt Width';
            app.tgt_width = uieditfield(app.vis_angUIFigure, 'numeric');
            app.tgt_width.ValueChangedFcn = createCallbackFcn(app, @tgt_width_updated, true);
            app.distanceLabel = uilabel(app.vis_angUIFigure);
            app.distanceLabel.Text = 'Distance';
            app.distance = uieditfield(app.vis_angUIFigure, 'numeric');
            app.distance.ValueDisplayFormat = '%.0f';
            app.distance.ValueChangedFcn = createCallbackFcn(app, @dist_updated, true);
            app.vis_angleLabel = uilabel(app.vis_angUIFigure);
            app.vis_angleLabel.Text = 'Visual Angle (°)';
            app.vis_angle = uieditfield(app.vis_angUIFigure, 'numeric');
            app.vis_angle.ValueDisplayFormat = '%5.2f';
            app.vis_angle.ValueChangedFcn = createCallbackFcn(app, @v_updated, true);
            app.vis_dioptLabel = uilabel(app.vis_angUIFigure);
            app.vis_dioptLabel.Text = 'Visual Angle (D)';
            app.vis_diopt = uieditfield(app.vis_angUIFigure, 'numeric');
            app.vis_diopt.ValueDisplayFormat = '%5.2f';
            app.vis_diopt.ValueChangedFcn = createCallbackFcn(app, @diopt_updated, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = vis_ang

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.vis_angUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startup)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.vis_angUIFigure)
        end
    end
end