classdef azel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        azelFigure               matlab.ui.Figure
        AzSlider                 matlab.ui.control.Slider
        ElSlider                 matlab.ui.control.Slider
        AzimuthEditFieldLabel    matlab.ui.control.Label
        azimuth                  matlab.ui.control.NumericEditField
        ElevationEditFieldLabel  matlab.ui.control.Label
        elevation                matlab.ui.control.NumericEditField
        D3Button                 matlab.ui.control.Button
        XYButton                 matlab.ui.control.Button
        XZButton                 matlab.ui.control.Button
        YZButton                 matlab.ui.control.Button
        UpdateButton             matlab.ui.control.Button
    end

   
   properties (Access = private)
      tgtfig % Description
      el
      az
      ga
   end
   
   methods (Access = private)
      
      function update_controls(app)
         app.AzSlider.Value = app.az;
         app.ElSlider.Value = app.el;
         app.azimuth.Value = app.az;
         app.elevation.Value = app.el;
      end
      
   end
   

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
         %%%%
         %%%%
         app.azelFigure.NextPlot='new';
         app.azelFigure.Tag='control';
         get_fig(app)
         %%%%
         %%%%
        end

        % Value changed function: AzSlider
        function az_sli_upd(app, event)
         value = app.AzSlider.Value;
         
        end

        % Value changed function: ElSlider
        function el_sli_upd(app, event)
         value = app.ElSlider.Value;
         
        end

        % Value changing function: AzSlider
        function az_sli_dynupd(app, event)
         new_az = round(event.Value,2);
         if new_az <= -179.9
            app.AzSlider.Value = 180;
            %new_az = 180;
         elseif new_az >=179.9
            app.AzSlider.Value = -180;
            %new_az = -180;
         end
         
         app.az = new_az;
         app.ga.View = [app.az, app.el];
         app.AzSlider.Value = app.az;
         app.azimuth.Value = app.az;
         
        end

        % Value changing function: ElSlider
        function el_sli_dynupd(app, event)
         new_el = round(event.Value,2);
         if new_el <= -179.9
            app.ElSlider.Value = 180;
            %new_el = 180;
         elseif new_el >= 179.9
            app.ElSlider.Value = -180;
            %new_el=-180;
         end
         
         app.el = new_el;
         app.ga.View = [app.az, app.el];
         app.ElSlider.Value = app.el;
         app.elevation.Value = app.el;
        end

        % Button pushed function: UpdateButton
        function get_fig(app, event)
         hotwin = findHotW;
         if ishandle(hotwin)
            app.tgtfig=hotwin;
         else
            disp('No eligible figure window available.')
            return
         end
         app.ga = app.tgtfig.CurrentAxes;
         azel = app.ga.View;
         app.az = fix(azel(1));
         app.el = fix(azel(2));
         update_controls(app)
         
        end

        % Value changed function: azimuth
        function az_text(app, event)
         value = app.azimuth.Value;
         app.AzSlider.Value = value;
         app.ga.View = [value app.el];
         
        end

        % Value changed function: elevation
        function el_text(app, event)
         value = app.elevation.Value;
         app.ElSlider.Value = value;
         app.ga.View = [app.az value];
        end

        % Button pushed function: D3Button
        function d3_view(app, event)
         app.az = -37.5;
         app.el = 30;
         app.ga.View = [app.az app.el];
         update_controls(app)
        end

        % Button pushed function: XYButton
        function xy_view(app, event)
         app.az = 0;
         app.el = 90;
         app.ga.View = [app.az app.el];
         update_controls(app)
         
        end

        % Button pushed function: XZButton
        function xz_view(app, event)
         app.az = 0;
         app.el = 0;
         app.ga.View = [app.az app.el];
         update_controls(app)
         
        end

        % Button pushed function: YZButton
        function yz_view(app, event)
         app.az = 90;
         app.el = 0;
         app.ga.View = [app.az app.el];
         update_controls(app)
         
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.azelFigure = uifigure;
            app.azelFigure.Name = 'RotPlot!';
            app.AzSlider = uislider(app.azelFigure);
            app.AzSlider.Limits = [-180 180];
            app.AzSlider.MajorTickLabels = {'', '', '', '', '', '', ''};
            app.AzSlider.ValueChangedFcn = createCallbackFcn(app, @az_sli_upd, true);
            app.AzSlider.ValueChangingFcn = createCallbackFcn(app, @az_sli_dynupd, true);
            app.AzSlider.MinorTicks = [];
            app.ElSlider = uislider(app.azelFigure);
            app.ElSlider.Limits = [-180 180];
            app.ElSlider.MajorTickLabels = {'', '', '', '', '', '', ''};
            app.ElSlider.ValueChangedFcn = createCallbackFcn(app, @el_sli_upd, true);
            app.ElSlider.ValueChangingFcn = createCallbackFcn(app, @el_sli_dynupd, true);
            app.ElSlider.MinorTicks = [];
            app.AzimuthEditFieldLabel = uilabel(app.azelFigure);
            app.AzimuthEditFieldLabel.Text = 'Azimuth';
            app.azimuth = uieditfield(app.azelFigure, 'numeric');
            app.azimuth.Limits = [-180 180];
            app.azimuth.ValueChangedFcn = createCallbackFcn(app, @az_text, true);
            app.ElevationEditFieldLabel = uilabel(app.azelFigure);
            app.ElevationEditFieldLabel.Text = 'Elevation';
            app.elevation = uieditfield(app.azelFigure, 'numeric');
            app.elevation.Limits = [-180 180];
            app.elevation.ValueChangedFcn = createCallbackFcn(app, @el_text, true);
            app.D3Button = uibutton(app.azelFigure, 'push');
            app.D3Button.ButtonPushedFcn = createCallbackFcn(app, @d3_view, true);
            app.D3Button.Text = '3D';
            app.XYButton = uibutton(app.azelFigure, 'push');
            app.XYButton.ButtonPushedFcn = createCallbackFcn(app, @xy_view, true);
            app.XYButton.Text = 'X,Y';
            app.XZButton = uibutton(app.azelFigure, 'push');
            app.XZButton.ButtonPushedFcn = createCallbackFcn(app, @xz_view, true);
            app.XZButton.Text = 'X,Z';
            app.YZButton = uibutton(app.azelFigure, 'push');
            app.YZButton.ButtonPushedFcn = createCallbackFcn(app, @yz_view, true);
            app.YZButton.Text = 'Y,Z';
            app.UpdateButton = uibutton(app.azelFigure, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @get_fig, true);
            app.UpdateButton.Text = 'Update';
        end
    end

    methods (Access = public)

        % Construct app
        function app = azel

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.azelFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.azelFigure)
        end
    end
end