classdef campos < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        camposFigure        matlab.ui.Figure
        YPosSlider          matlab.ui.control.Slider
        ZPosSlider          matlab.ui.control.Slider
        YPosLabel           matlab.ui.control.Label
        ypos                matlab.ui.control.NumericEditField
        ZPosLabel           matlab.ui.control.Label
        zpos                matlab.ui.control.NumericEditField
        D3Button            matlab.ui.control.Button
        XYButton            matlab.ui.control.Button
        XZButton            matlab.ui.control.Button
        YZButton            matlab.ui.control.Button
        UpdateButton        matlab.ui.control.Button
        XPosSlider          matlab.ui.control.Slider
        XPosEditFieldLabel  matlab.ui.control.Label
        xpos                matlab.ui.control.NumericEditField
    end

   
   properties (Access = private)
      tgtfig % Description
      z
      y
      x
      ga
   end
   
   methods (Access = private)
      
      function update_controls(app)
         app.XPosSlider.Value = app.x;
         app.YPosSlider.Value = app.y;
         app.ZPosSlider.Value = app.z;
         app.xpos.Value = app.x;
         app.ypos.Value = app.y;
         app.zpos.Value = app.z;
      end
      
   end
   

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
         %%%%
         %%%%
         app.camposFigure.NextPlot='new';
         app.camposFigure.Tag='control';
         get_fig(app)
         %%%%
         %%%%
        end

        % Value changing function: YPosSlider
        function y_sli_dynupd(app, event)
         new_y = round(event.Value,2);
         %{
         if new_y <= -179.9
            app.ySlider.Value = 180;
            %new_y = 180;
         elseif new_y >=179.9
            app.ySlider.Value = -180;
            %new_y = -180;
         end
         %}
         app.y = new_y;
         app.ga.CameraPosition = [app.x, new_y, app.z];
         app.YPosSlider.Value = app.y;
         app.ypos.Value = app.y;
         
        end

        % Value changing function: ZPosSlider
        function z_sli_dynupd(app, event)
         new_z = round(event.Value,2);
         %{
         if new_z <= -179.9
            app.zSlider.Value = 180;
            %new_z = 180;
         elseif new_z >= 179.9
            app.zSlider.Value = -180;
            %new_z=-180;
         end
         %}
         app.z = new_z;
         app.ga.CameraPosition = [app.x, app.y, new_z];
         app.ZPosSlider.Value = app.z;
         app.zpos.Value = app.z;
        end

        % Button pushed function: UpdateButton
        function get_fig(app, event)
         hotwin = findHotW;
         if ishandle(hotwin)
            app.tgtfig=hotwin;
         else
            disp('No eligible figure window available.')
            return
         end
         app.ga = app.tgtfig.CurrentAxes;
         camxyz = app.ga.CameraPosition;
         app.x = fix(camxyz(1));
         app.y = fix(camxyz(2));
         app.z = fix(camxyz(3));
         update_controls(app)
         
        end

        % Value changed function: ypos
        function y_text(app, event)
         value = app.ypos.Value;
         app.YPosSlider.Value = value;
         app.ga.CameraPosition = [app.x, value, app.z];
         
        end

        % Value changed function: zpos
        function z_text(app, event)
         value = app.zpos.Value;
         app.ZPosSlider.Value = value;
         app.ga.CameraPosition = [app.x, app.y, value];
        end

        % Button pushed function: D3Button
        function d3_view(app, event)
         %app.y = -37.5;
         %app.z = 30;
         app.ga.View = [-37.5 30];
         camxyz = app.ga.CameraPosition;
         app.x = fix(camxyz(1));
         app.y = fix(camxyz(2));
         app.z = fix(camxyz(3));
         update_controls(app)
        end

        % Button pushed function: XYButton
        function xy_view(app, event)
         %app.y = 0;
         %app.z = 90;
         app.ga.View = [0 90];
         camxyz = app.ga.CameraPosition;
         app.x = fix(camxyz(1));
         app.y = fix(camxyz(2));
         app.z = fix(camxyz(3));
         update_controls(app)
         
        end

        % Button pushed function: XZButton
        function xz_view(app, event)
         %app.y = 0;
         %app.z = 0;
         app.ga.View = [0 0];
         camxyz = app.ga.CameraPosition;
         app.x = fix(camxyz(1));
         app.y = fix(camxyz(2));
         app.z = fix(camxyz(3));
         update_controls(app)
         
        end

        % Button pushed function: YZButton
        function yz_view(app, event)
         %app.y = 90;
         %app.z = 0;
         app.ga.View = [90 0];
         camxyz = app.ga.CameraPosition;
         app.x = fix(camxyz(1));
         app.y = fix(camxyz(2));
         app.z = fix(camxyz(3));
         update_controls(app)
         
        end

        % Value changing function: XPosSlider
        function x_sli_dynupd(app, event)
         new_x = round(event.Value,2);
         %{
         if new_x <= -179.9
            app.xSlider.Value = 180;
            %new_x = 180;
         elseif new_x >=179.9
            app.xSlider.Value = -180;
            %new_x = -180;
         end
         %}
         app.x = new_x;
         app.ga.CameraPosition = [new_x, app.y, app.z];
         app.XPosSlider.Value = app.x;
         app.xpos.Value = app.x;
         
        end

        % Value changed function: xpos
        function x_text(app, event)
         value = app.xpos.Value;
         app.XPosSlider.Value = value;
         app.ga.CameraAngle = [value, app.y, app.z];
         
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.camposFigure = uifigure;
            app.camposFigure.Name = 'Camera!';
            app.YPosSlider = uislider(app.camposFigure);
            app.YPosSlider.Limits = [-1000000 1000000];
            app.YPosSlider.MajorTickLabels = {'', '', '', '', ''};
            app.YPosSlider.ValueChangingFcn = createCallbackFcn(app, @y_sli_dynupd, true);
            app.YPosSlider.MinorTicks = [];
            app.ZPosSlider = uislider(app.camposFigure);
            app.ZPosSlider.Limits = [-1000000 1000000];
            app.ZPosSlider.MajorTickLabels = {'', '', '', '', ''};
            app.ZPosSlider.ValueChangingFcn = createCallbackFcn(app, @z_sli_dynupd, true);
            app.ZPosSlider.MinorTicks = [];
            app.YPosLabel = uilabel(app.camposFigure);
            app.YPosLabel.Text = 'Y Pos';
            app.ypos = uieditfield(app.camposFigure, 'numeric');
            app.ypos.ValueDisplayFormat = '%.0f';
            app.ypos.ValueChangedFcn = createCallbackFcn(app, @y_text, true);
            app.ZPosLabel = uilabel(app.camposFigure);
            app.ZPosLabel.Text = 'Z Pos';
            app.zpos = uieditfield(app.camposFigure, 'numeric');
            app.zpos.ValueDisplayFormat = '%.0f';
            app.zpos.ValueChangedFcn = createCallbackFcn(app, @z_text, true);
            app.D3Button = uibutton(app.camposFigure, 'push');
            app.D3Button.ButtonPushedFcn = createCallbackFcn(app, @d3_view, true);
            app.D3Button.Text = '3D';
            app.XYButton = uibutton(app.camposFigure, 'push');
            app.XYButton.ButtonPushedFcn = createCallbackFcn(app, @xy_view, true);
            app.XYButton.Text = 'X,Y';
            app.XZButton = uibutton(app.camposFigure, 'push');
            app.XZButton.ButtonPushedFcn = createCallbackFcn(app, @xz_view, true);
            app.XZButton.Text = 'X,Z';
            app.YZButton = uibutton(app.camposFigure, 'push');
            app.YZButton.ButtonPushedFcn = createCallbackFcn(app, @yz_view, true);
            app.YZButton.Text = 'Y,Z';
            app.UpdateButton = uibutton(app.camposFigure, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @get_fig, true);
            app.UpdateButton.Text = 'Update';
            app.XPosSlider = uislider(app.camposFigure);
            app.XPosSlider.Limits = [-1000000 1000000];
            app.XPosSlider.MajorTickLabels = {'', '', '', '', ''};
            app.XPosSlider.ValueChangingFcn = createCallbackFcn(app, @x_sli_dynupd, true);
            app.XPosSlider.MinorTicks = [];
            app.XPosEditFieldLabel = uilabel(app.camposFigure);
            app.XPosEditFieldLabel.Text = 'X Pos';
            app.xpos = uieditfield(app.camposFigure, 'numeric');
            app.xpos.ValueDisplayFormat = '%.0f';
            app.xpos.ValueChangedFcn = createCallbackFcn(app, @x_text, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = campos

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.camposFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.camposFigure)
        end
    end
end